classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlayButton                   matlab.ui.control.Button
        RewindButton                 matlab.ui.control.Button
        ForwardButton                matlab.ui.control.Button
        UploadVideoButton            matlab.ui.control.Button
        CurrentFrameEditFieldLabel   matlab.ui.control.Label
        CurrentFrameEditField        matlab.ui.control.NumericEditField
        PreviousFrameEditFieldLabel  matlab.ui.control.Label
        PreviousFrameEditField       matlab.ui.control.NumericEditField
        FrametoseeEditFieldLabel     matlab.ui.control.Label
        FrametoseeEditField          matlab.ui.control.NumericEditField
        EnterButton                  matlab.ui.control.Button
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        SliderLabel                  matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SelectButton                 matlab.ui.control.Button
        Trajectory1EditFieldLabel    matlab.ui.control.Label
        Trajectory1EditField         matlab.ui.control.NumericEditField
        Trajectory2EditFieldLabel    matlab.ui.control.Label
        Trajectory2EditField         matlab.ui.control.NumericEditField
        BreakButton                  matlab.ui.control.Button
        JoinButton                   matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        OtherTextAreaLabel           matlab.ui.control.Label
        OtherTextArea                matlab.ui.control.TextArea
        RelabelButton                matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        UploadTrajButton             matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        UploadRawButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        obj = 0; % will contain video object of whatever video user chooses
        obj2 = 0; % raw video on which plot_trajectories will be called
        Pause = true;
        stop_label = false;
        curr_frame = 1;
        prev_frame = 1;
        errors = {};
        traj_files = 0; % trajectory file (.mat)
    end
    
    methods (Access = public)
        % this function plays a video by reading every frame, depending
        % when user pauses or plays a video
        function play_video(app)
            % checks whether there are any more frames left to read
            while hasFrame(app.obj)
                app.prev_frame = app.curr_frame;
                if app.Pause == true
                    break
                end
                % reads next frame
                vidFrame = readFrame(app.obj);
                app.curr_frame = app.curr_frame + 1;
                % displays frame on axes
                image(vidFrame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                %app.UIAxes.Visible = "off";
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                app.Slider.Value = app.curr_frame;
            end
            if ~hasFrame(app.obj)
                app.PlayButton.Text = "Play Again";
                app.PlayButton.BackgroundColor = [1 0 0];
                app.FrametoseeEditField.Visible = 'on';
                app.FrametoseeEditFieldLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                app.Pause = false;
            end
        end 
        
        % this function converts a mp4 file to an avi file by 
        % reading every frame from the mp4 file and writing those frames
        % to an avi file
        function convert_mp4_to_avi(~, mp4_filename)
            reader = VideoReader(mp4_filename);
            cell = extractBetween(mp4_filename, 1, strlength(mp4_filename) - 4); 
            new_filename = strcat(cell{1}, '.avi');
            writer = VideoWriter(new_filename);
            writer.FrameRate = 60;
            open(writer);
            time = 0;
            f = waitbar(time, 'Please wait...');
            while hasFrame(reader)
                img = readFrame(reader);
                writeVideo(writer, img);
                time = time + 1;
                waitbar(time/reader.NumFrames, f, 'converting mp4 to avi');
            end
            close(f);
            close(writer);
        end
        
        % this function checks whether a given file the user clicks on
        % is in the current directory of the app
        function results = is_member(~, filename, file_list)
            is_in = false;
            for n = 1 : length(file_list)
                if strcmp(filename, file_list{n})
                    is_in = true;
                    break;
                end
            end
            results = is_in;
        end
        
        function choose_coordinate(app)
            % this function will be used to decide whether a coordinate
            % point that the user clicks on is inside the image
            while true
                [x, y, button] = ginput(1);
                if is_coordinate_valid(app, x, y, app.UIAxes.XLim(2), app.UIAxes.YLim(2)) && (button ~= 3) 
                    app.xEditField.Value = x;
                    app.yEditField.Value = y;
                    draw_circle(app, x, y, 20);
                end
                % click on right mouse button to stop gathering coordinates
                if button == 3
                    break;
                end
               
            end
        end
        
        function results = is_coordinate_valid(~, x, y, width, height)
            is_valid = false;
            x_rect_coordinate = 1;
            y_rect_coordinate = 1;
            if (x >= x_rect_coordinate) && (x <= x_rect_coordinate + width)
                if (y >= y_rect_coordinate) && (y <= y_rect_coordinate + height)
                    is_valid = true;
                end
            end
            results = is_valid;
        end
        
        function draw_circle(app, x, y, r)
            % x and y are the coordinates of the center of the circle
            % r is the radius of the circle
            % 0.01 is the angle step, bigger values will draw the circle faster but
            % you might notice imperfections (not very smooth)
            ang=0:0.01:2*pi; 
            xp=r*cos(ang);
            yp=r*sin(ang);
            hold on
            plot(app.UIAxes, x+xp, y+yp, "Color", [1,1,0], "LineWidth", 2);
            hold off
        end

        function redraw(app, trajs, traj_starts)
            plot_trajectories(app.obj2, trajs, traj_starts);
        end

        function result = iterate_traj(app, trajs, starts)
            % chose trajectory that is the closest to user selected point
            num_traj = length(starts);
            best = [0 inf];
            for i = 1:num_traj
                if (app.curr_frame >= starts{i}) && (app.curr_frame < (starts{i} + size(trajs{i}, 1)))
                    row = app.curr_frame - starts{i} + 1;
                    curr_traj = trajs{i};
                    x = [app.xEditField.Value app.yEditField.Value];
                    y = [curr_traj(row, 1) curr_traj(row, 2)];
                    dist = norm(y-x);
                    if (dist < best(2))
                        best(2) = dist;
                        best(1) = i;
                    end
                end
            end
            result = best(1);
        end
            
        function find_closest_traj(app)
           % use Euclidean distance to find trajectory closest to user's
           % selection
           if (app.xEditField.Value == 0) && (app.yEditField.Value == 0)
              message = sprintf('Please select a trajectory');
              uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
              return;
           end
           trajs = app.traj_files.trajectories;
           starts = app.traj_files.traj_starts;
           selected_traj = iterate_traj(app, trajs, starts);
           if (app.Trajectory1EditField.Value == 0 || app.Trajectory2EditField.Value ~= 0)
              app.Trajectory1EditField.Value = selected_traj;
              app.Trajectory2EditField.Value = 0;
           else
              app.Trajectory2EditField.Value = selected_traj; 
           end
           return;
        end

        function result = make_copy(~, arr)
            dim = size(arr);
            result = cell(dim(1), dim(2));
            for i = 1:dim(2)
                result{i} = arr{i};
            end
        end

        function new_array = fill_values(~, arr, row, x0, y0, x1, y1, step)
            slope_x = (x1 - x0)/step;
            slope_y = (y1 - y0)/step;
            next_row = row + 1;
            while (next_row < row + step)
                input = next_row - row;
                row_val = [((slope_x * input) + x0) ((slope_y * input) + y0)];
                arr(next_row, :) = row_val;
                next_row = next_row + 1;
            end
            new_array = arr;
        end

        function break_traj(app)
            if app.obj == 0 || isa(app.traj_files, 'double') || isa(app.obj2, 'double')
               message = sprintf('Please upload all files');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            % break selected trajectory at the current frame
            if (floor(app.Trajectory1EditField.Value) ~= app.Trajectory1EditField.Value)
                message = sprintf('Trajectory number should be an integer value');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            if (app.Trajectory1EditField.Value <= 0 || app.Trajectory1EditField.Value > length(app.traj_files.traj_starts))
               message = sprintf('Please select a valid trajectory');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            traj = app.Trajectory1EditField.Value;
            row = app.curr_frame - app.traj_files.traj_starts{traj} + 1;
            traj_starts = make_copy(app, app.traj_files.traj_starts);
            trajectories = make_copy(app, app.traj_files.trajectories);

            traj_starts{end + 1} = app.curr_frame;
            curr_traj = trajectories{traj};
            trajectories{end + 1} = curr_traj(row:end, :);
            curr_traj(row:end, :) = [];
            trajectories{traj} = curr_traj;

            filename = sprintf('break_%d_%d.mat', traj, app.curr_frame);
            save(filename, 'traj_starts', 'trajectories');
            redraw(app, trajectories, traj_starts);
            upload(app);
            app.traj_files = load(filename);
        end

        function join_traj(app)
            if app.obj == 0 || isa(app.traj_files, 'double') || isa(app.obj2, 'double')
               message = sprintf('No file chosen! \n Consider choosing a file next time');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            % join selected trajectories at the current frame
            if (floor(app.Trajectory1EditField.Value) ~= app.Trajectory1EditField.Value || floor(app.Trajectory2EditField.Value) ~= app.Trajectory2EditField.Value)
                message = sprintf('Trajectory numbers should be integer values');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            if (app.Trajectory1EditField.Value <= 0 || app.Trajectory1EditField.Value > length(app.traj_files.traj_starts) ...
                    || app.Trajectory2EditField.Value <= 0 || app.Trajectory2EditField.Value > length(app.traj_files.traj_starts))
               message = sprintf('Please select valid trajectories');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            traj1 = app.Trajectory1EditField.Value;
            traj2 = app.Trajectory2EditField.Value;
            row = app.traj_files.traj_starts{traj2} - app.traj_files.traj_starts{traj1} + 1;
            endrow = length(app.traj_files.trajectories{traj2}); 
            traj_starts = make_copy(app, app.traj_files.traj_starts);
            trajectories = make_copy(app, app.traj_files.trajectories);

            curr_traj = trajectories{traj1};
            curr_traj(row:(endrow + row - 1), :) = app.traj_files.trajectories{traj2};
            trajectories{traj1} = curr_traj;
            traj_starts(traj2) = [];
            trajectories(traj2) = [];

            filename = sprintf('join_%d_%d_%d.mat', traj1, traj2, app.curr_frame);
            save(filename, 'traj_starts', 'trajectories');
            redraw(app, trajectories, traj_starts);
            upload(app);
            app.traj_files = load(filename);
        end

        function delete_traj(app)
            if app.obj == 0 || isa(app.traj_files, 'double') || isa(app.obj2, 'double')
               message = sprintf('No file chosen! \n Consider choosing a file next time');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            % delete selected trajectory from current frame onwards
            if (floor(app.Trajectory1EditField.Value) ~= app.Trajectory1EditField.Value)
                message = sprintf('Trajectory number should be an integer value');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            if (app.Trajectory1EditField.Value <= 0 || app.Trajectory1EditField.Value > length(app.traj_files.traj_starts))
               message = sprintf('Please select a valid trajectory');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            traj = app.Trajectory1EditField.Value;
            traj_starts = make_copy(app, app.traj_files.traj_starts);
            trajectories = make_copy(app, app.traj_files.trajectories);

            if (app.curr_frame == traj_starts{traj})
               traj_starts(traj) = [];
               trajectories(traj) = [];  
            else
               row = app.curr_frame - app.traj_files.traj_starts{traj} + 1;
               curr_traj = trajectories{traj};
               curr_traj(row:end, :) = [];
               trajectories{traj} = curr_traj;
            end

            filename = sprintf('delete_%d_%d.mat', traj, app.curr_frame);
            save(filename, 'traj_starts', 'trajectories');
            redraw(app, trajectories, traj_starts);
            upload(app);
            app.traj_files = load(filename);
        end

        function report_other(app)
            if app.obj == 0 || isa(app.traj_files, 'double') || isa(app.obj2, 'double')
               message = sprintf('No file chosen! \n Consider choosing a file next time');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            if (floor(app.Trajectory1EditField.Value) ~= app.Trajectory1EditField.Value)
                message = sprintf('Trajectory number should be an integer value');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            if (app.Trajectory1EditField.Value <= 0 || app.Trajectory1EditField.Value > length(app.traj_files.traj_starts))
               message = sprintf('Please select a valid trajectory');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return; 
            end
            report = app.OtherTextArea.Value;

            row = {app.curr_frame, app.Trajectory1EditField.Value, report};
            app.errors(end + 1, :) = row;
        end

        function relabel(app)
            if app.obj == 0 || isa(app.traj_files, 'double') || isa(app.obj2, 'double')
               message = sprintf('No file chosen! \n Consider choosing a file next time');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
            end
            app.StopButton.Visible = "on";

            traj_starts = make_copy(app, app.traj_files.traj_starts);
            trajectories = make_copy(app, app.traj_files.trajectories);
            temp_col = double.empty;

            frame = app.curr_frame;
            row = 1;
            traj_starts{end + 1} = app.curr_frame;

            while (frame <= app.obj.NumFrames && ~app.stop_label)
                display_frame = read(app.obj, frame);
                app.curr_frame = frame;
                app.Pause = true;
                if frame == 1
                    app.prev_frame = 1;
                else
                    app.prev_frame = frame - 1;
                end
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                image(display_frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                app.Slider.Value = app.curr_frame;
                
                app.UIFigure.HandleVisibility = 'callback';
                choose_coordinate(app);
                app.UIFigure.HandleVisibility = 'off';
                row_val = [app.xEditField.Value app.yEditField.Value];
                temp_col(row, :) = row_val;

                if (row ~= 1)
                    prev_coords = temp_col(row - 60, :);
                    temp_col = fill_values(app, temp_col, row - 60, prev_coords(1), prev_coords(2), app.xEditField.Value, app.yEditField.Value, 60);
                end

                row = row + 60;
                
                % fixes the fps of the video
                frame = frame + 60;
            end

            trajectories{end + 1} = temp_col;
            filename = sprintf('relabel.mat');
            save(filename, 'traj_starts', 'trajectories');
            redraw(app, trajectories, traj_starts);
            upload(app);
            app.traj_files = load(filename);
        end
        
        function play_pause_replay_video(app)
            % plays and pauses a video, depending if the play/pause button
            % is triggered
            % when playing a video
            if strcmp(app.PlayButton.Text, "Play")
                app.PlayButton.Text = "Pause";  
                app.Pause = false;
                app.PlayButton.BackgroundColor = [0 0 1];
                app.FrametoseeEditField.Visible = 'off';
                app.FrametoseeEditFieldLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                app.SelectButton.Visible = 'off';
                app.SelectButton.Enable = 'off';
                app.SelectButton.Visible = 'off';
                app.SelectButton.Enable = 'off';
                play_video(app);
            % when pausing a video    
            elseif strcmp(app.PlayButton.Text, "Pause") 
                app.PlayButton.Text = "Play";
                app.FrametoseeEditField.Visible = 'on';
                app.FrametoseeEditFieldLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                app.Pause = true;
                app.PlayButton.BackgroundColor = [0 1 0];
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                app.SelectButton.Visible = 'on';
                app.SelectButton.Enable = 'on';
                app.SelectButton.Visible = 'on';
                app.SelectButton.Enable = 'on';
            % when replaying a video    
            else 
                app.PlayButton.Text = "Pause";
                app.PlayButton.BackgroundColor = [0 0 1];
                app.Pause = false;
                app.curr_frame = 0;
                app.prev_frame = 0;
                app.obj.CurrentTime = 0;
                app.FrametoseeEditField.Visible = 'off';
                app.FrametoseeEditFieldLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.Slider.Enable = 'on';
                app.Slider.Visible = 'on';
                app.SelectButton.Visible = 'off';
                app.SelectButton.Enable = 'off';
                play_video(app);
            end
        end
    
        % extracts the frame of the current video in a frames folder
        function extract_frames(app, obj) 
            % reads the video
            video = read(obj);
            % reads the total number of frames
            frames = obj.NumberOfFrames;
            % make frames directory
            status = mkdir('frames');
    
            for frame = 1 : frames
                % converting integer to string
                num_str = num2str(frame);
    
                % concatenating 2 strings
                file_name = strcat(num_str, '.jpg');
                vid = video(:, :, :, frame);
            
                % go into directory where frames will go
                cd frames
            
                % exporting the frames
                imwrite(vid, file_name)
                cd ..
            end
        end
        
        function raw_file = upload_raw(~, file)
            [parent_dir, name, ~] = fileparts(file);
            disp(parent_dir);
            file_list = dir(strcat(parent_dir, '/*_raw.avi'));
            file_list = {file_list.name};
            name_raw = strsplit(name, '_');
            name_raw = name_raw{1, 1};
            
            for i = 1 : length(file_list)
                if contains(file_list{1, i}, name_raw)
                    raw_file = file_list{1, i};
                    disp('getting raw video');
                    disp(raw_file);
                end
            end
            raw_file = strcat(parent_dir, '/', raw_file);
            disp(raw_file);
        end

        function upload(app)
            % returns name of the file a user clicks on
            [file, path] = uigetfile({'*.mp4'; '*.avi'});
            if file == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            
            full_path = strcat(path, file);
            [filepath, name, ext] = fileparts(full_path);
            % checks whether file chosen is an mp4 file to convert to an
            % avi file
            if (strcmp(ext, ".mp4"))
                % returns a cell array of avi files in the current directory
                file_list = dir('*.avi');
                file_list = {file_list.name};
                mp4_file_name = extractBetween(file, 1, strlength(file) - 4);
                % checks whether file user clicked on is available as an avi
                % file
                if ~is_member(app,strcat(mp4_file_name{1}, '.avi'), file_list)
                    convert_mp4_to_avi(app, file);
                    file = strcat(mp4_file_name{1}, '.avi');
                    full_path = strcat(path, file);
                end   
            end
            
            % displays first frame in axes
            app.obj = VideoReader(full_path);
            
            %raw_file = upload_raw(app, file);
            
            %upload_traj(app, file);
            %app.obj2 = VideoReader(raw_file);
            %disp('uploaded raw video, hidden from user');
            
            vidFrame = readFrame(app.obj);
            % Display image and stretch to fill axes
            I = imshow(vidFrame, 'Parent', app.UIAxes);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            set(app.UIAxes, 'Visible', 'on');
            app.Slider.Visible = 'on';
            app.Slider.Enable = 'on';
            app.Slider.Limits = [1 app.obj.NumFrames];
            
           
            app.PlayButton.Text = "Play";
            app.curr_frame = 1;
            app.prev_frame = 1;
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
            app.FrametoseeEditField.Limits = [1 app.obj.NumFrames];
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           % give error message if user pressed "Play" but no video is
           % chosen
           if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
           end 
           play_pause_replay_video(app);
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            upload(app);
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            % give error message if user pressed "Rewind" but no video is
            % chosen
            if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % if frame being read is the first frame and user wants to
            % rewind, do nothing
            if app.curr_frame == 1
                frame = read(app.obj, app.curr_frame);
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                image(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
    
            else 
                frame = read(app.obj, app.curr_frame - 1);
                if app.curr_frame == app.obj.NumFrames
                    app.PlayButton.Text = 'Play';
                end
                app.curr_frame = app.curr_frame - 1;
                app.prev_frame = app.prev_frame - 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.curr_frame - 1;
                image(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                app.Slider.Value = app.curr_frame;
            end
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % give error message if user pressed "Forward" but no video is
            % chosen
            if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            if app.curr_frame < app.obj.NumFrames
                frame = read(app.obj, app.curr_frame + 1);
                app.curr_frame = app.curr_frame + 1;
                app.prev_frame = app.prev_frame + 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.curr_frame - 1;
                image(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                app.Slider.Value = app.curr_frame;
            % if frame being read is the very last frame and user wants to
            % forward, do nothing
            else 
                frame = read(app.obj, app.curr_frame);
                image(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % give error message if user pressed "Enter" but no video is
            % chosen
            if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % gets frame user wants to read and displays that frame on the
            % axes
            if (floor(app.FrametoseeEditField.Value) ~= app.FrametoseeEditField.Value)
                message = sprintf('Please enter an integer value');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            frame = read(app.obj, app.FrametoseeEditField.Value);
            app.curr_frame = app.FrametoseeEditField.Value;
            app.Pause = true;
            if app.FrametoseeEditField.Value == 1
                app.prev_frame = 1;
            else 
                app.prev_frame = app.FrametoseeEditField.Value - 1;
            end
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
            app.PlayButton.Text = 'Play';
            image(frame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); % changed
            app.Slider.Value = app.curr_frame;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = floor(app.Slider.Value);
            app.curr_frame = value;
            if (value == 1)
                app.prev_frame = 1;
            else
                app.prev_frame = value - 1;
            end
            app.CurrentFrameEditField.Value = value;
            app.PreviousFrameEditField.Value = app.prev_frame;
            frame = read(app.obj, value);
            image(frame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); % changed
            if (value == app.obj.NumFrames)
                app.PlayButton.Text = "Play Again";
            else 
                app.PlayButton.Text = "Play"; 
            end
        end

        % Key press function: UIFigure
        function SpacebarPress(app, event)
            key = event.Key;
            if key == "space"
                if app.obj ~= 0
                    play_pause_replay_video(app);
                end
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % give error message if user pressed "Plot" but no video is
            % chosen
            if app.obj == 0 || isa(app.traj_files, 'double')
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % ginput triggered here in next three lines
            app.UIFigure.HandleVisibility = 'callback';
            choose_coordinate(app);
            app.UIFigure.HandleVisibility = 'off';
            find_closest_traj(app);
        end

        % Button pushed function: BreakButton
        function BreakButtonPushed(app, event)
            break_traj(app)
        end

        % Button pushed function: JoinButton
        function JoinButtonPushed(app, event)
            join_traj(app)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            report_other(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete_traj(app) 
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            errors = app.errors;
            trajs = app.traj_files;
            save("other_errors.mat", 'errors');
            save("final_trajectories.mat", 'trajs');
            app.delete;
        end

        % Button pushed function: RelabelButton
        function RelabelButtonPushed(app, event)
            relabel(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_label = true;
            app.StopButton.Visible = "off";
        end

        % Button pushed function: UploadTrajButton
        function UploadTrajButtonPushed(app, event)
            file = uigetfile({'*.mat'});
            if file == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            app.traj_files = load(file);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Trajectory1EditField.Value = 0;
            app.Trajectory2EditField.Value = 0;
        end

        % Button pushed function: UploadRawButton
        function UploadRawButtonPushed(app, event)
            file = uigetfile({'*.mp4'; '*.avi'});
            if file == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % returns a cell array of avi files in the current directory
            file_list = dir('*.avi');
            file_list = {file_list.name};
            mp4_file_name = extractBetween(file, 1, strlength(file) - 4);
            % checks whether file user clicked on is available as an avi
            % file
            if ~is_member(app,strcat(mp4_file_name{1}, '.avi'), file_list)
                convert_mp4_to_avi(app, file);
                file = strcat(mp4_file_name{1}, '.avi');
            end
            app.obj2 = file;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @SpacebarPress, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RewindButton = uibutton(app.UIFigure, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.UploadVideoButton = uibutton(app.UIFigure, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameEditField.Editable = 'off';
            app.PreviousFrameEditFieldLabel = uilabel(app.UIFigure);
            app.PreviousFrameEditFieldLabel.Text = 'Previous Frame';
            app.PreviousFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreviousFrameEditField.Editable = 'off';
            app.FrametoseeEditFieldLabel = uilabel(app.UIFigure);
            app.FrametoseeEditFieldLabel.Text = 'Frame # to see';
            app.FrametoseeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Editable = 'off';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Editable = 'off';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.Trajectory1EditFieldLabel = uilabel(app.UIFigure);
            app.Trajectory1EditFieldLabel.Text = 'Trajectory 1';
            app.Trajectory1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Trajectory2EditFieldLabel = uilabel(app.UIFigure);
            app.Trajectory2EditFieldLabel.Text = 'Trajectory 2';
            app.Trajectory2EditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakButton = uibutton(app.UIFigure, 'push');
            app.BreakButton.ButtonPushedFcn = createCallbackFcn(app, @BreakButtonPushed, true);
            app.BreakButton.Text = 'Break';
            app.JoinButton = uibutton(app.UIFigure, 'push');
            app.JoinButton.ButtonPushedFcn = createCallbackFcn(app, @JoinButtonPushed, true);
            app.JoinButton.Text = 'Join';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.OtherTextAreaLabel = uilabel(app.UIFigure);
            app.OtherTextAreaLabel.Text = 'Other';
            app.OtherTextArea = uitextarea(app.UIFigure);
            app.RelabelButton = uibutton(app.UIFigure, 'push');
            app.RelabelButton.ButtonPushedFcn = createCallbackFcn(app, @RelabelButtonPushed, true);
            app.RelabelButton.Text = 'Relabel';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.UploadTrajButton = uibutton(app.UIFigure, 'push');
            app.UploadTrajButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTrajButtonPushed, true);
            app.UploadTrajButton.Text = 'Upload Traj.';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UploadRawButton = uibutton(app.UIFigure, 'push');
            app.UploadRawButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRawButtonPushed, true);
            app.UploadRawButton.Text = 'Upload Raw';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end