classdef human_data_labeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        E_RelabelButton              matlab.ui.control.Button
        E_MissingButton              matlab.ui.control.Button
        OtherTextAreaLabel_2         matlab.ui.control.Label
        MissingButton                matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        UndoButton                   matlab.ui.control.Button
        DisentangleButton            matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        RelabelButton                matlab.ui.control.Button
        OtherTextArea                matlab.ui.control.TextArea
        OtherTextAreaLabel           matlab.ui.control.Label
        DeleteButton                 matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        JoinButton                   matlab.ui.control.Button
        BreakButton                  matlab.ui.control.Button
        Trajectory2EditField         matlab.ui.control.NumericEditField
        Trajectory2EditFieldLabel    matlab.ui.control.Label
        Trajectory1EditField         matlab.ui.control.NumericEditField
        Trajectory1EditFieldLabel    matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
        FrametoseeEditField          matlab.ui.control.NumericEditField
        FrametoseeEditFieldLabel     matlab.ui.control.Label
        PreviousFrameEditField       matlab.ui.control.NumericEditField
        PreviousFrameEditFieldLabel  matlab.ui.control.Label
        CurrentFrameEditField        matlab.ui.control.NumericEditField
        CurrentFrameEditFieldLabel   matlab.ui.control.Label
        ForwardButton                matlab.ui.control.Button
        RewindButton                 matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % initialize variables
        obj = 0; % will contain video object of whatever video user chooses
        obj2 = 0; % raw video on which plot_trajectories will be called
        fps = 0;
        obj_num_frames = 0;
        obj2_num_frames = 0;

        Pause = true;
        stop_label = false;
        curr_frame = 1;
        prev_frame = 1;
        errors = {};
        traj1_changed = false;
        traj2_changed = false;
        traj_files = 0; % trajectory file (.mat)
        help_mode = false;
        debug_mode = false;

        % file name
        %app_path = "/home/hdc/Desktop/data/";
        app_path = "/media/ssd2/HDC2/n1";
        session_id = 4;
        segment_id = 3;
        vid_offset = 0;
        
        fname = "";
        tj_fname = "";
        mat_fname = "";
        outname = "";
        out_mat_fname = "";
      
        ins_path = "";
        vid_path = "";
        traj_path = "";
        traj_vid_fname = "";
        raw_vid_fname = "";
        traj_fname = "";
        out_vid_fname = "";
        out_traj_fname = "";
        out_err_fname = "";
        out_hist_fname = "";

        trajectories = {};
        traj_starts = {};
        change_history = {};

        color_fname = "standard_colors.mat"
        color_code = [];
        window_size = 0;

        instructions = {};
        front_frames = {};
%         back_frames = {};

        stat_idx_l = 0;
        stat_idx_h = 0;

        % meta level switches
        help_page_on = false;
        expert_mode = true;
        play_speed = 3; %skip frame count
        label_skip = 10;
        segment_length = 6000;
        
    end
    
    methods (Access = public)
        % this function plays a video by reading every frame, depending
        % when user pauses or plays a video
        function play_video(app)
            % checks whether there are any more frames left to read
            while (app.curr_frame + app.play_speed) <= app.obj_num_frames
                app.prev_frame = app.curr_frame;
                if app.Pause == true
                    break
                end
                % reads next frame
                app.curr_frame = app.curr_frame + app.play_speed;
                app.prev_frame = app.curr_frame - 1;
                vidFrame = app.front_frames{app.curr_frame};

                % displays frame on axes
                cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                %app.UIAxes.Visible = "off";
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                app.Slider.Value = app.curr_frame;
                drawnow;
                pause(1/60);
            end
            if app.curr_frame > (app.obj_num_frames - app.play_speed)
                app.PlayButton.Text = "Play Again";
                app.PlayButton.BackgroundColor = [1 0 0];
                app.FrametoseeEditField.Visible = 'on';
                app.FrametoseeEditFieldLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                app.Pause = false;
                drawnow;
            end
        end

        function play_pause_replay_video(app)
            % plays and pauses a video, depending if the play/pause button
            % is triggered
            % when playing a video
            if strcmp(app.PlayButton.Text, "Play")
                app.PlayButton.Text = "Pause";  
                app.Pause = false;
                app.PlayButton.BackgroundColor = [0 0 1];
                app.FrametoseeEditField.Visible = 'off';
                app.FrametoseeEditFieldLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                drawnow;
                play_video(app);
            % when pausing a video    
            elseif strcmp(app.PlayButton.Text, "Pause") 
                app.PlayButton.Text = "Play";
                app.FrametoseeEditField.Visible = 'on';
                app.FrametoseeEditFieldLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                app.Pause = true;
                app.PlayButton.BackgroundColor = [0 1 0];
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                drawnow;
            % when replaying a video    
            else 
                app.PlayButton.Text = "Pause";
                app.PlayButton.BackgroundColor = [0 0 1];
                app.Pause = false;
                app.curr_frame = 0;
                app.prev_frame = 0;
                app.FrametoseeEditField.Visible = 'off';
                app.FrametoseeEditFieldLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.Slider.Enable = 'on';
                app.Slider.Visible = 'on';
                drawnow;
                play_video(app);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function redraw(app, redraw_st, redraw_ed)
            % This function redraws the video frames from redraw_st to
            % redraw_ed to reflect the changes the users made.
            num_colors = size(app.color_code, 1);
            ws = app.window_size;

            redraw_st = max(redraw_st, app.vid_offset + 1);
            redraw_ed = min(redraw_ed, app.obj2_num_frames + app.vid_offset);

            d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', 'Message', 'Updating the video ...');
            drawnow;

            %initialize masks for trajectories that already exist
            num_ped = length(app.trajectories);
            ped_set = zeros(1, num_ped);
            mask_set = cell(1, num_ped);
            for i = 1:num_ped
                mask_set{i} = zeros(app.obj.Height, app.obj.Width, 3, "uint8");
            end
            curr_pt_set = zeros(num_ped, 2);
            vid_idx = redraw_st - 1;
            for i = 1:num_ped
                if (vid_idx >= app.traj_starts{i}) && (vid_idx < (app.traj_starts{i} + size(app.trajectories{i}, 1)))
                    traj = app.trajectories{i};
                    for j = 1:(vid_idx - app.traj_starts{i} + 1)
                        pt = round(traj(j, :));
                        window_r = max(pt(2)-ws, 1):min(pt(2)+ws, app.obj.Height);
                        window_c = max(pt(1)-ws, 1):min(pt(1)+ws, app.obj.Width);
                        mask = ones(length(window_r), length(window_c), 3);
                        mask_set{i}(window_r, window_c, :) = mask;
                    end
                end
            end

            % redraw by replacing the front frames with repainted back
            % frames (non-painted frames)
            [vid_idx, redraw_st, redraw_ed]
            vid_idx = vid_idx + 1;
            while vid_idx < redraw_ed
%                 frame = app.back_frames{vid_idx};
                app.obj2.CurrentTime = (vid_idx - app.vid_offset - 1) / app.fps;
                frame = readFrame(app.obj2);
                d.Value = (vid_idx - redraw_st) / (redraw_ed - redraw_st);
                % updating the painted masks
                for i = 1:num_ped
                    if (vid_idx >= app.traj_starts{i}) && (vid_idx < (app.traj_starts{i} + size(app.trajectories{i}, 1)))
                        if ped_set(i) == 0
                            ped_set(i) = 1;
                        end
                        pt = app.trajectories{i}(vid_idx - app.traj_starts{i} + 1, :);
                        pt = round(pt);
            
                        window_r = max(pt(2)-ws, 1):min(pt(2)+ws, app.obj.Height);
                        window_c = max(pt(1)-ws, 1):min(pt(1)+ws, app.obj.Width);
                        mask = ones(length(window_r), length(window_c), 3);
                        mask_set{i}(window_r, window_c, :) = mask;
                        
                        curr_pt_set(i, :) = pt;
                    else
                        if ped_set(i) == 1
                            ped_set(i) = 0;
                        end
                    end
                end

                % draw them on the frame
                for i = 1:num_ped
                    if ped_set(i) == 1
                        if (i > app.stat_idx_l) && (i <= app.stat_idx_h)
                            color = [0, 0, 0];
                        else
                            color = app.color_code(mod(i, num_colors) + 1, :);
                        end
                        
                        color_mask = mask_set{i};
                        color_mask(:, :, 1) = color_mask(:, :, 1) * color(1);
                        color_mask(:, :, 2) = color_mask(:, :, 2) * color(2);
                        color_mask(:, :, 3) = color_mask(:, :, 3) * color(3);
                        frame = frame .* (1 - mask_set{i}) + color_mask;
                        frame = insertText(frame, curr_pt_set(i, :), num2str(i));
                    end
                end
                app.front_frames{vid_idx - app.vid_offset} = frame;
                vid_idx = vid_idx + 1;
            end

            close(d);

        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function result = iterate_traj(app, trajs, starts)
            % chose trajectory that is the closest to user selected point
            num_traj = length(starts);
            best = [0 inf inf];
            for i = 1:num_traj
                if ((app.curr_frame + app.vid_offset) >= starts{i}) && ...
                   ((app.curr_frame + app.vid_offset) < (starts{i} + size(trajs{i}, 1)))
                    %row = app.curr_frame - starts{i} + 1;
                    curr_traj = trajs{i};
                    curr_fr_idx = (app.curr_frame + app.vid_offset) - starts{i};
                    x = [app.xEditField.Value app.yEditField.Value];
                    y = [curr_traj(1:curr_fr_idx, 1) curr_traj(1:curr_fr_idx, 2)];
                    [dist, idx] = min(sum((y-x) .^ 2, 2));
                    if (dist < best(2))
                        best(1) = i;
                        best(2) = dist;
                        best(3) = idx;
                    end
                end
            end
            result = best;
        end
            
        function [result_traj, result_dist,  result_frame] = find_closest_traj(app)
           % use Euclidean distance to find trajectory closest to user's
           % selection
           % returns: traj_idx, min dist to traj in pixels, traj's local
           % frame idx (global = local + traj_start)
           if (app.xEditField.Value == 0) && (app.yEditField.Value == 0)
               message = sprintf('Please select a trajectory');
               uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
               return;
           end
           trajs = app.traj_files.trajectories;
           starts = app.traj_files.traj_starts;
           result = iterate_traj(app, trajs, starts);
           result_traj = result(1); 
           result_dist = result(2);  
           result_frame = result(3);
        end

        function [x, y] = drawpoint_wrapper(app)
            app.UIFigure.HandleVisibility = 'callback';
            hold on;

            %draw point method
            roi = drawpoint;
            x = roi.Position(1);
            y = roi.Position(2);
            delete(roi);
            app.xEditField.Value = x;
            app.yEditField.Value = y;
        end

        function [traj_idx, traj_dist, traj_frame] = choose_coordinate(app, traj_uid)
            % this function will be used to decide whether a coordinate
            % point that the user clicks on is inside the imshow
            
            [~, ~] = drawpoint_wrapper(app);
            [traj_idx, traj_dist, traj_frame] = find_closest_traj(app);
            if traj_uid == 1
                app.Trajectory1EditField.Value = traj_idx;
            elseif traj_uid == 2
                app.Trajectory2EditField.Value = traj_idx;
            else
                message = sprintf('traj_uid has to be 1 or 2!');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
            end

            
            %ginput method
%             s = 0;
%             while true
%                 [x, y, button] = ginput(1);
%                 if button ~= 3
%                     app.xEditField.Value = x;
%                     app.yEditField.Value = y;
%                     if s ~= 0
%                         delete(s);
%                     end
%                     s = scatter(app.UIAxes,x , y, 'y', 'filled');1
%                     [traj_idx, traj_dist, traj_frame] = find_closest_traj(app);
%                     if traj_uid == 1
%                         app.Trajectory1EditField.Value = traj_idx;
%                     elseif traj_uid == 2
%                         app.Trajectory2EditField.Value = traj_idx;
%                     else
%                         message = sprintf('traj_uid has to be 1 or 2!');
%                         uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
%                     end
%                 end
%                 % click on right mouse button to stop gathering coordinates
%                 if button == 3
%                     break;
%                 end
%             end
            
            app.UIFigure.HandleVisibility = 'off';
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [new_traj, redraw_st, redraw_ed] = relabel_tool(app)
            % First: drag the scroll bar at the bottom and select the frame to begin
            % Second: select in the frame to relabel
            % Third: when done, select anywhere above the frame (make y = 0)

            select_x = 1280;
            select_y = 1024;
            while select_y == 1024
                [select_x, select_y] = drawpoint_wrapper(app);
            end
            redraw_st = app.curr_frame + app.vid_offset;
            new_traj = [select_x, select_y];

            while select_y > 0
                if (app.curr_frame + app.label_skip) < app.obj_num_frames
                    % reads next frame
                    app.curr_frame = app.curr_frame + app.label_skip;
                    app.prev_frame = app.curr_frame - 1;
                    vidFrame = app.front_frames{app.curr_frame};
                    % displays frame on axes
                    cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
                    set(app.UIAxes, 'Visible', 'on');
                    app.CurrentFrameEditField.Value = app.curr_frame;
                    app.PreviousFrameEditField.Value = app.prev_frame;
                    app.Slider.Value = app.curr_frame;
                    drawnow;
                else
                    redraw_ed = app.curr_frame + app.vid_offset;
                    return
                end

                [select_x, select_y] = drawpoint_wrapper(app);
                if select_y <= 0
                    break
                end
                old_x = new_traj(end, 1);
                old_y = new_traj(end, 2);
                fill_traj = fill_values(app, old_x, old_y, select_x, select_y, app.label_skip);
                new_traj = [new_traj; fill_traj; select_x, select_y];
                
            end
            redraw_ed = app.curr_frame - app.label_skip + app.vid_offset;
            new_traj(end, :) = [];
            return
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [new_array] = fill_values(~, x0, y0, x1, y1, duration)
            % use linear interpolation to fill in the middle values
            slope_x = (x1 - x0)/duration;
            slope_y = (y1 - y0)/duration;
            step = 0;
            new_array = zeros(duration - 1, 2);
            while (step < (duration - 1))
                step = step + 1;
                row_val = [((slope_x * step) + x0) ((slope_y * step) + y0)];
                new_array(step, :) = row_val;
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function break_traj(app)
            % break selected trajectory at the current frame

            app.HelpButton.Visible = 'off';

            app.Trajectory1EditField.Visible = 'on';
            app.Trajectory1EditFieldLabel.Visible = 'on';
            app.SelectButton.Visible = 'on';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.Trajectory1EditField.Value = 1;
            app.Trajectory1EditField.Limits = [1, length(app.traj_starts)];
            uiwait(app.UIFigure);

            [traj_idx, ~, traj_frame] = choose_coordinate(app, 1);
            if traj_frame == 1
                traj_frame = traj_frame + 1;
            end

%             redraw_st = app.traj_starts{traj_idx};
            redraw_st = app.traj_starts{traj_idx} + traj_frame;
            redraw_ed = app.traj_starts{traj_idx} + size(app.trajectories{traj_idx}, 1);

            % break the trajectory
            app.traj_starts{end + 1} = app.traj_starts{traj_idx} + traj_frame + 1;
            curr_traj = app.trajectories{traj_idx};
            backup_traj = curr_traj;
            app.trajectories{end + 1} = curr_traj((traj_frame + 1):end, :);
            curr_traj((traj_frame - 1):end, :) = [];
            app.trajectories{traj_idx} = curr_traj;

            % redraw and update
            %filename = sprintf('break_%d_%d.mat', traj, app.curr_frame);
            %save(filename, 'app.traj_starts', 'app.trajectories');
            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.change_history{end + 1} = {"break", redraw_st, redraw_ed, traj_idx, backup_traj};

            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory1EditField.Visible = 'off';
            app.SelectButton.Visible = 'off';

            app.HelpButton.Visible = 'on';
        end

        function join_traj(app)
            % Join 2 trajectories that are supposed to be together

            app.HelpButton.Visible = 'off';

            app.Trajectory1EditField.Visible = 'on';
            app.Trajectory1EditFieldLabel.Visible = 'on';
            app.Trajectory2EditField.Visible = 'on';
            app.Trajectory2EditFieldLabel.Visible = 'on';
            app.SelectButton.Visible = 'on';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.Trajectory1EditField.Value = 1;
            app.Trajectory2EditField.Value = 1;1
            app.Trajectory1EditField.Limits = [1, length(app.traj_starts)];
            app.Trajectory2EditField.Limits = [1, length(app.traj_starts)];
            uiwait(app.UIFigure);

            % join selected trajectories at the current frame
            traj1_idx = app.Trajectory1EditField.Value;
            traj2_idx = app.Trajectory2EditField.Value;
            if app.traj_starts{traj2_idx} < app.traj_starts{traj1_idx}
                tmp = traj1_idx;
                traj1_idx = traj2_idx;
                traj2_idx = tmp;
            end
            traj1 = app.trajectories{traj1_idx};
            traj2 = app.trajectories{traj2_idx};
            traj1_ed = app.traj_starts{traj1_idx} + length(traj1);
            traj2_st = app.traj_starts{traj2_idx};
            fill_time = traj2_st - traj1_ed;

            backup_traj1 = traj1;
            backup_traj2 = traj2;
            backup_traj2_st = traj2_st;
            
            if fill_time > 0
                inter_traj = fill_values(app, traj1(end, 1), traj1(end, 2), ...
                                              traj2(1, 1), traj2(1, 2), ...
                                              fill_time);
                curr_traj = [traj1; inter_traj; traj2];
            else
                traj1 = traj1(1:(end-abs(fill_time)), :);
                curr_traj = [traj1; traj2];
            end
            app.trajectories{traj1_idx} = curr_traj;
            app.traj_starts{traj2_idx} = Inf;
            app.trajectories{traj2_idx} = [0,0];
            
            % redraw and update
            %filename = sprintf('join_%d_%d_%d.mat', traj1, traj2, app.curr_frame);
            %save(filename, 'traj_starts', 'trajectories');
            redraw_st = app.traj_starts{traj1_idx} + length(traj1);
            redraw_ed = app.traj_starts{traj1_idx} + length(curr_traj);
            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.change_history{end + 1} = {"join", redraw_st, redraw_ed, traj1_idx, traj2_idx, backup_traj1, backup_traj2, backup_traj2_st};

            app.Trajectory1EditField.Visible = 'off';
            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory2EditField.Visible = 'off';
            app.Trajectory2EditFieldLabel.Visible = 'off';
            app.SelectButton.Visible = 'off';

            app.HelpButton.Visible = 'on';
        end

        function delete_traj(app)
            % Delete noisy or redundant trajectories

            app.HelpButton.Visible = 'off';

            app.Trajectory1EditField.Visible = 'on';
            app.Trajectory1EditFieldLabel.Visible = 'on';
            app.SelectButton.Visible = 'on';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.Trajectory1EditField.Value = 1;
            app.Trajectory1EditField.Limits = [1, length(app.traj_starts)];
            uiwait(app.UIFigure);

            % delete selected trajectory
            traj_idx = app.Trajectory1EditField.Value;
            redraw_st = app.traj_starts{traj_idx};
            redraw_ed = app.traj_starts{traj_idx} + length(app.trajectories{traj_idx});
            backup_traj_st = app.traj_starts{traj_idx};
            backup_traj = app.trajectories{traj_idx};
            app.traj_starts{traj_idx} = Inf;
            app.trajectories{traj_idx} = [0,0];
            
            % redraw and update
            %filename = sprintf('delete_%d_%d.mat', traj, app.curr_frame);
            %save(filename, 'app.traj_starts', 'app.trajectories');
            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.change_history{end + 1} = {"delete", redraw_st, redraw_ed, traj_idx, backup_traj, backup_traj_st};

            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory1EditField.Visible = 'off';
            app.SelectButton.Visible = 'off';

            app.HelpButton.Visible = 'on';
        end

        function disentangle(app)
            % If the tracking of 2 pedestrians becomes confused
            % This will disentagle them

            app.HelpButton.Visible = 'off';

            app.Trajectory1EditField.Visible = 'on';
            app.Trajectory1EditFieldLabel.Visible = 'on';
            app.Trajectory2EditField.Visible = 'on';
            app.Trajectory2EditFieldLabel.Visible = 'on';
            app.SelectButton.Visible = 'on';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.Trajectory1EditField.Value = 1;
            app.Trajectory2EditField.Value = 1;
            app.Trajectory1EditField.Limits = [1, length(app.traj_starts)];
            app.Trajectory2EditField.Limits = [1, length(app.traj_starts)];
            uiwait(app.UIFigure);

            [tmp_idx, ~, traj_frame] = choose_coordinate(app, 1);
            abs_frame = app.traj_starts{tmp_idx} + traj_frame;

            % join selected trajectories at the current frame
            traj1_idx = app.Trajectory1EditField.Value;
            traj2_idx = app.Trajectory2EditField.Value;
            traj1 = app.trajectories{traj1_idx};
            traj2 = app.trajectories{traj2_idx};
            backup_traj1 = traj1;
            backup_traj2 = traj2;
            
            traj1_break = abs_frame - app.traj_starts{traj1_idx};
            traj2_break = abs_frame - app.traj_starts{traj2_idx};
            app.trajectories{traj1_idx} = [traj1(1:traj1_break, :); traj2(traj2_break+1:end, :)];
            app.trajectories{traj2_idx} = [traj2(1:traj2_break, :); traj1(traj1_break+1:end, :)];
            
            % redraw and update
            %filename = sprintf('join_%d_%d_%d.mat', traj1, traj2, app.curr_frame);
            %save(filename, 'traj_starts', 'trajectories');
%             redraw_st = min(app.traj_starts{traj1_idx}, app.traj_starts{traj2_idx});
            redraw_st = abs_frame;
            redraw_ed = max(app.traj_starts{traj1_idx} + length(traj1), ...
                            app.traj_starts{traj2_idx} + length(traj2));
            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.change_history{end + 1} = {"disentangle", redraw_st, redraw_ed, traj1_idx, traj2_idx, backup_traj1, backup_traj2};

            app.Trajectory1EditField.Visible = 'off';
            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory2EditField.Visible = 'off';
            app.Trajectory2EditFieldLabel.Visible = 'off';
            app.SelectButton.Visible = 'off';

            app.HelpButton.Visible = 'on';
        end

        function report_other(app)
            % Report other errors that can't be fixed by provided methods

            app.HelpButton.Visible = 'off';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            report = app.OtherTextArea.Value;

            row = {app.curr_frame + app.vid_offset, report};
            app.errors{end + 1} = row;

            app.OtherTextArea.Value = "";

            app.HelpButton.Visible = 'on';
        end

        function relabel(app)
            % If a trajectory tracks badly and needs to be retracked

            app.HelpButton.Visible = 'off';

            app.Trajectory1EditField.Visible = 'on';
            app.Trajectory1EditFieldLabel.Visible = 'on';
            app.SelectButton.Visible = 'on';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.Trajectory1EditField.Value = 1;
            app.Trajectory1EditField.Limits = [1, length(app.traj_starts)];
            uiwait(app.UIFigure);

            report = app.Trajectory1EditField.Value;

            row = {app.curr_frame + app.vid_offset, report};
            app.errors{end + 1} = row;

            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory1EditField.Visible = 'off';
            app.SelectButton.Visible = 'off';

            app.HelpButton.Visible = 'on';
        end

        function expert_relabel(app)
            % If a trajectory tracks badly and needs to be retracked
            % An expert can just directlty relabel it
            app.HelpButton.Visible = 'off';

            app.Trajectory1EditField.Visible = 'on';
            app.Trajectory1EditFieldLabel.Visible = 'on';
            app.SelectButton.Visible = 'on';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.Trajectory1EditField.Value = 1;
            app.Trajectory1EditField.Limits = [1, length(app.traj_starts)];
            uiwait(app.UIFigure);

            traj_idx = app.Trajectory1EditField.Value;
            backup_traj_st = app.traj_starts{traj_idx};
            backup_traj = app.trajectories{traj_idx};

            % replace the traj with the manual labelled one
            [new_traj, redraw_st, redraw_ed] = relabel_tool(app);
            og_traj = app.trajectories{traj_idx};
            og_start = app.traj_starts{traj_idx};
            if redraw_st <= og_start
                app.traj_starts{traj_idx} = redraw_st;
                traj_1 = [];
            else
                new_st_offset = redraw_st - og_start;
                traj_1 = og_traj(1:new_st_offset, :);
            end
            if redraw_ed >= (og_start + length(og_traj))
                traj_2 = [];
            else
                new_ed_offset = og_start + length(og_traj) - redraw_ed;
                traj_2 = og_traj((end - new_ed_offset + 1):end, :);
            end
            app.trajectories{traj_idx} = [traj_1;new_traj;traj_2];

            % redraw and update AGAIN
            redraw_ed = app.traj_starts{traj_idx} + length(app.trajectories{traj_idx});
            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.change_history{end + 1} = {"relabel", redraw_st, redraw_ed, traj_idx, backup_traj, backup_traj_st};

            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory1EditField.Visible = 'off';
            app.SelectButton.Visible = 'off';

            app.HelpButton.Visible = 'on';
        end

        function missing(app)
            % If a trajectory is missing

            app.HelpButton.Visible = 'off';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            [x, y] = drawpoint_wrapper(app);
            report = [x, y];

            row = {app.curr_frame + app.vid_offset, report};
            app.errors{end + 1} = row;

            app.HelpButton.Visible = 'on';
        end

        function expert_missing(app)
            % If a trajectory is missing and needs to be labelled by an
            % expert

            app.HelpButton.Visible = 'off';

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            % Manually label the new trajectory
            [new_traj, redraw_st, redraw_ed] = relabel_tool(app);
            app.traj_starts{end + 1} = redraw_st;
            app.trajectories{end + 1} = new_traj;
            traj_idx = length(app.traj_starts);

            % redraw and update
            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.change_history{end + 1} = {"missing", redraw_st, redraw_ed, traj_idx};

            app.HelpButton.Visible = 'on';
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function undo(app)
            % if the user makes an incorrect change, this button will undo
            % the last change.

            app.HelpButton.Visible = 'off';

            if isempty(app.change_history)
                message = sprintf('Nothing to undo');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end

            history = app.change_history{end};
            app.change_history(end) = [];

            type = history{1};
            redraw_st = history{2};
            redraw_ed = history{3};
            if strcmp(type, "break")
                traj_idx = history{4};
                traj = history{5};
                app.trajectories{traj_idx} = traj;
                app.traj_starts(end) = [];
                app.trajectories(end) = [];
            elseif strcmp(type, "join")
                traj1_idx = history{4};
                traj2_idx = history{5};
                traj1 = history{6};
                traj2 = history{7};
                traj2_st = history{8};
                app.traj_starts{traj2_idx} = traj2_st;
                app.trajectories{traj1_idx} = traj1;
                app.trajectories{traj2_idx} = traj2;
            elseif strcmp(type, "delete")
                traj_idx = history{4};
                traj = history{5};
                traj_st = history{6};
                app.traj_starts{traj_idx} = traj_st;
                app.trajectories{traj_idx} = traj;
            elseif strcmp(type, "disentangle")
                traj1_idx = history{4};
                traj2_idx = history{5};
                traj1 = history{6};
                traj2 = history{7};
                app.trajectories{traj1_idx} = traj1;
                app.trajectories{traj2_idx} = traj2;
            elseif strcmp(type, "relabel")
                traj_idx = history{4};
                traj = history{5};
                traj_st = history{6};
                app.traj_starts{traj_idx} = traj_st;
                app.trajectories{traj_idx} = traj;
            elseif strcmp(type, "missing")
                traj_idx = history{4};
                app.traj_starts{traj_idx} = Inf;
                app.trajectories{traj_idx} = [0,0];
            end

            redraw(app, redraw_st, redraw_ed);
            vidFrame = app.front_frames{app.curr_frame};
            cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); 
            drawnow;

            app.HelpButton.Visible = 'on';
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function help_page(app)
            % This function displays instructions

            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            app.help_mode = true;

            app.HelpButton.Visible = 'off';
            app.UndoButton.Visible = 'off';
            app.DisentangleButton.Visible = 'off';
            app.RelabelButton.Visible = 'off';
            app.OtherTextArea.Visible = 'off';
            app.OtherTextAreaLabel.Visible = 'off';
            app.DeleteButton.Visible = 'off';
            app.DoneButton.Visible = 'off';
            app.SubmitButton.Visible = 'off';
            app.JoinButton.Visible = 'off';
            app.BreakButton.Visible = 'off';
            app.MissingButton.Visible = 'off';
            app.Slider.Visible = 'off';
            app.yEditField.Visible = 'off';
            app.yEditFieldLabel.Visible = 'off';
            app.xEditField.Visible = 'off';
            app.xEditFieldLabel.Visible = 'off';
            app.EnterButton.Visible = 'off';
            app.FrametoseeEditField.Visible = 'off';
            app.FrametoseeEditFieldLabel.Visible = 'off';
            app.PreviousFrameEditField.Visible = 'off';
            app.PreviousFrameEditFieldLabel.Visible = 'off';
            app.CurrentFrameEditField.Visible = 'off';
            app.CurrentFrameEditFieldLabel.Visible = 'off';
            app.ForwardButton.Visible = 'off';
            app.RewindButton.Visible = 'off';
            app.PlayButton.Visible = 'off';

            % display instructions one by one, click to proceed to next
            prev_pos = app.UIAxes.Position;
            app.UIAxes.Position(1) = 1;
            app.UIAxes.Position(2) = 1;
            num_ins = length(app.instructions);
            for i=1:num_ins
                ins = app.instructions{i};
                app.UIAxes.Position(3) = size(ins, 2);
                app.UIAxes.Position(4) = size(ins, 1);
                cla(app.UIAxes);imshow(ins, 'Parent', app.UIAxes);
                drawnow;
                uiwait(app.UIFigure);
            end
            
            app.HelpButton.Visible = 'on';
            app.UndoButton.Visible = 'on';
            app.DisentangleButton.Visible = 'on';
            app.RelabelButton.Visible = 'on';
            app.OtherTextArea.Visible = 'on';
            app.OtherTextAreaLabel.Visible = 'on';
            app.DeleteButton.Visible = 'on';
            app.DoneButton.Visible = 'on';
            app.SubmitButton.Visible = 'on';
            app.JoinButton.Visible = 'on';
            app.BreakButton.Visible = 'on';
            app.MissingButton.Visible = 'on';
            app.Slider.Visible = 'on';
            app.yEditField.Visible = 'on';
            app.yEditFieldLabel.Visible = 'on';
            app.xEditField.Visible = 'on';
            app.xEditFieldLabel.Visible = 'on';
            app.EnterButton.Visible = 'on';
            app.FrametoseeEditField.Visible = 'on';
            app.FrametoseeEditFieldLabel.Visible = 'on';
            app.PreviousFrameEditField.Visible = 'on';
            app.PreviousFrameEditFieldLabel.Visible = 'on';
            app.CurrentFrameEditField.Visible = 'on';
            app.CurrentFrameEditFieldLabel.Visible = 'on';
            app.ForwardButton.Visible = 'on';
            app.RewindButton.Visible = 'on';
            app.PlayButton.Visible = 'on';

            app.help_mode = false;
            
            app.UIAxes.Position = prev_pos;
            if ~(app.obj == 0)
                vidFrame = app.front_frames{app.curr_frame};
                cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
                drawnow;
            else
                app.UIAxes.Visible = 'off';
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function result = make_copy(~, arr)
            dim = size(arr);
            result = cell(dim(1), dim(2));
            for i = 1:dim(2)
                result{i} = arr{i};
            end
        end

        function preload(app)
            app.traj_vid_fname
            app.raw_vid_fname
            app.obj = VideoReader(app.traj_vid_fname);
            app.fps = app.obj.FrameRate;
            app.obj_num_frames = app.obj.Duration * app.fps;
            app.obj2 = VideoReader(app.raw_vid_fname);
            app.obj2_num_frames = app.obj2.Duration * app.fps;
            app.front_frames = cell(1, app.obj_num_frames);       
%             app.back_frames = cell(1, app.obj2_num_frames);
            d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', 'Message', 'Opening the application');
            drawnow;

            i = 1;
            while hasFrame(app.obj)
                frame = readFrame(app.obj);
                app.front_frames{i} = frame;
                d.Value = i / app.obj_num_frames;
                i = i + 1;
            end

%             i = 1;
%             while hasFrame(app.obj2)
%                 frame = readFrame(app.obj2);
%                 app.back_frames{i} = frame;
%                 d.Value = i / app.obj2_num_frames / 2 + 0.5;
%                 i = i + 1;
%             end

            close(d);
        end

        function upload(app)     
            % displays first frame in axes         
            vidFrame = app.front_frames{1};
            % Display imshow and stretch to fill axes
            I = imshow(vidFrame, 'Parent', app.UIAxes);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            set(app.UIAxes, 'Visible', 'on');
            app.Slider.Visible = 'on';
            app.Slider.Enable = 'on';
            app.Slider.Limits = [1 app.obj_num_frames];
            
           
            app.PlayButton.Text = "Play";
            app.curr_frame = 1;
            app.prev_frame = 1;
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
            app.FrametoseeEditField.Limits = [1 app.obj_num_frames];
        end

        function upload_traj(app)
            file = app.traj_fname;
            if file == ""
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            app.traj_files = load(file);
            app.traj_starts = make_copy(app, app.traj_files.traj_starts);
            app.trajectories = make_copy(app, app.traj_files.trajectories);
            app.stat_idx_l = app.traj_files.dyn_idx_threshold;
            app.stat_idx_h = length(app.trajectories);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Instructions
            ins1 = imread(app.ins_path + "HW.jpg");
            app.instructions{1} = ins1;

            % Load files
            color_data = load(app.ins_path + app.color_fname, "colors", "window_size");
            app.color_code = color_data.colors;
            app.window_size = color_data.window_size;
            
            if app.debug_mode == true
                app.app_path = "";
                app.vid_path = "";
                app.traj_path = "";
                app.traj_vid_fname = "example_detections.avi";
                app.raw_vid_fname = "example_raw.avi";
                app.traj_fname = "example.mat";
                app.vid_offset = 0;
            else
                app.vid_path = app.app_path + "/stitched_videos_mp4";
                app.traj_path = app.app_path + "/track_results";
                app.fname = string(app.session_id) + "_" + string(app.segment_id);
                app.tj_fname = string(app.session_id) + "_" + string(app.segment_id) + "_traj";
                app.mat_fname = string(app.session_id);
                app.traj_vid_fname = app.vid_path + "/" + app.tj_fname + ".avi";
                app.raw_vid_fname = app.vid_path + "/" + app.fname + ".avi";
                app.traj_fname = app.traj_path + "/" + app.mat_fname + "_s.mat";
                app.vid_offset = app.segment_id * app.segment_length;
            end

            preload(app);
            upload(app);
            upload_traj(app);

            if app.help_page_on == true
                help_page(app);
            else
                if ~(app.obj == 0)
                    vidFrame = app.front_frames{app.curr_frame};
                    %set(app.UIAxes, 'Visible', 'on');
                    cla(app.UIAxes);imshow(vidFrame, "Parent", app.UIAxes);
                    disp("showing video ...");
                    drawnow;
                else
                    app.UIAxes.Visible = 'off';
                end
            end

            if app.expert_mode == true
                app.E_RelabelButton.Visible = 'on';
                app.E_RelabelButton.Enable = 'on';
                app.E_MissingButton.Visible = 'on';
                app.E_MissingButton.Enable = 'on';
            end
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           % give error message if user pressed "Play" but no video is
           % chosen
           if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
           end 
           play_pause_replay_video(app);
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            % give error message if user pressed "Rewind" but no video is
            % chosen
            if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            % if frame being read is the first frame and user wants to
            % rewind, do nothing
            if app.curr_frame == 1
                frame = app.front_frames{app.curr_frame};
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                cla(app.UIAxes);imshow(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
    
            else 
                frame = app.front_frames{app.curr_frame - 1};
                if app.curr_frame == app.obj_num_frames
                    app.PlayButton.Text = 'Play';
                end
                app.curr_frame = app.curr_frame - 1;
                app.prev_frame = app.prev_frame - 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.curr_frame - 1;
                cla(app.UIAxes);imshow(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                app.Slider.Value = app.curr_frame;
            end
            drawnow;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % give error message if user pressed "Forward" but no video is
            % chosen
            if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            if app.curr_frame < app.obj_num_frames
                frame = app.front_frames{app.curr_frame + 1};
                app.curr_frame = app.curr_frame + 1;
                app.prev_frame = app.prev_frame + 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.curr_frame - 1;
                cla(app.UIAxes);imshow(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
                app.Slider.Value = app.curr_frame;
            % if frame being read is the very last frame and user wants to
            % forward, do nothing
            else 
                frame = app.front_frames{app.curr_frame};
                cla(app.UIAxes);imshow(frame, "Parent", app.UIAxes);
                set(app.UIAxes, 'Visible', 'on'); % changed
            end
            drawnow;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % give error message if user pressed "Enter" but no video is
            % chosen
            if app.obj == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            % gets frame user wants to read and displays that frame on the
            % axes
            if (floor(app.FrametoseeEditField.Value) ~= app.FrametoseeEditField.Value)
                message = sprintf('Please enter an integer value');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            frame = app.front_frames{app.FrametoseeEditField.Value};
            app.curr_frame = app.FrametoseeEditField.Value;
            app.Pause = true;
            if app.FrametoseeEditField.Value == 1
                app.prev_frame = 1;
            else 
                app.prev_frame = app.FrametoseeEditField.Value - 1;
            end
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
            app.PlayButton.Text = 'Play';
            cla(app.UIAxes);imshow(frame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); % changed
            app.Slider.Value = app.curr_frame;
            drawnow;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % pause the video if playing
            if strcmp(app.PlayButton.Text, "Pause")
                play_pause_replay_video(app)
            end

            value = floor(app.Slider.Value);
            app.curr_frame = value;
            if (value == 1)
                app.prev_frame = 1;
            else
                app.prev_frame = value - 1;
            end
            app.CurrentFrameEditField.Value = value;
            app.PreviousFrameEditField.Value = app.prev_frame;
            frame = app.front_frames{value};
            cla(app.UIAxes);imshow(frame, "Parent", app.UIAxes);
            set(app.UIAxes, 'Visible', 'on'); % changed
            if (value == app.obj_num_frames)
                app.PlayButton.Text = "Play Again";
            else 
                app.PlayButton.Text = "Play"; 
            end
            app.Slider.Value = app.curr_frame;
            drawnow;
        end

        % Key press function: UIFigure
        function SpacebarPress(app, event)
            key = event.Key;
            if key == "space"
                if app.obj ~= 0
                    play_pause_replay_video(app);
                end
            end
        end

        % Button pushed function: BreakButton
        function BreakButtonPushed(app, event)
            break_traj(app)
        end

        % Button pushed function: JoinButton
        function JoinButtonPushed(app, event)
            join_traj(app)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            report_other(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delete_traj(app) 
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.debug_mode == true
                app.out_vid_fname = "example_out.avi";
                app.out_traj_fname = "example_out.mat";
                app.out_err_fname = "example_err.mat";
                app.out_hist_fname = "example_hist.mat";
            else
                app.outname = string(app.session_id) + "_" + string(app.segment_id) + "_out";
                app.out_vid_fname = app.vid_path + "/" + app.outname + ".avi";
                app.out_traj_fname = app.traj_path + "/" + app.outname + ".mat";
                app.out_err_fname = app.traj_path + "/" + app.outname + "_err.mat";
                app.out_hist_fname = app.traj_path + "/" + app.outname + "_hist.mat";
            end

            errs = app.errors;
            traj_starts = app.traj_starts;
            trajectories = app.trajectories;
            histories = app.change_history;
            save(app.out_err_fname, 'errs');
            save(app.out_traj_fname, 'traj_starts', 'trajectories');
            save(app.out_hist_fname, 'histories');

%             v_write = VideoWriter(app.out_vid_fname);
%             v_write.FrameRate = app.obj.FrameRate;
%             d = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', 'Message', 'Closing the application');
%             drawnow;
%             open(v_write);
%             num_frames = length(app.front_frames);
%             for i = 1:num_frames
%                 frame = app.front_frames{i};
%                 writeVideo(v_write, frame);
%                 d.Value = i / num_frames;
%             end
%             close(v_write);
%             close(d);

            close(app.UIFigure);
        end

        % Button pushed function: RelabelButton
        function RelabelButtonPushed(app, event)
            relabel(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_label = true;
            app.StopButton.Visible = "off";
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Button pushed function: DisentangleButton
        function DisentangleButtonPushed(app, event)
            disentangle(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            undo(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.help_mode
                uiresume(app.UIFigure);
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            help_page(app);
        end

        % Button pushed function: MissingButton
        function MissingButtonPushed(app, event)
            missing(app);
        end

        % Button pushed function: E_RelabelButton
        function E_RelabelButtonPushed(app, event)
            expert_relabel(app);
        end

        % Button pushed function: E_MissingButton
        function E_MissingButtonPushed(app, event)
            expert_missing(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @SpacebarPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RewindButton = uibutton(app.UIFigure, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameEditField.Editable = 'off';
            app.PreviousFrameEditFieldLabel = uilabel(app.UIFigure);
            app.PreviousFrameEditFieldLabel.Text = 'Previous Frame';
            app.PreviousFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreviousFrameEditField.Editable = 'off';
            app.FrametoseeEditFieldLabel = uilabel(app.UIFigure);
            app.FrametoseeEditFieldLabel.Text = 'Frame # to see';
            app.FrametoseeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Editable = 'off';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Editable = 'off';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.Trajectory1EditFieldLabel = uilabel(app.UIFigure);
            app.Trajectory1EditFieldLabel.Visible = 'off';
            app.Trajectory1EditFieldLabel.Text = 'Trajectory 1';
            app.Trajectory1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Trajectory1EditField.RoundFractionalValues = 'on';
            app.Trajectory1EditField.Visible = 'off';
            app.Trajectory2EditFieldLabel = uilabel(app.UIFigure);
            app.Trajectory2EditFieldLabel.Visible = 'off';
            app.Trajectory2EditFieldLabel.Text = 'Trajectory 2';
            app.Trajectory2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Trajectory2EditField.RoundFractionalValues = 'on';
            app.Trajectory2EditField.Visible = 'off';
            app.BreakButton = uibutton(app.UIFigure, 'push');
            app.BreakButton.ButtonPushedFcn = createCallbackFcn(app, @BreakButtonPushed, true);
            app.BreakButton.Text = 'Break';
            app.JoinButton = uibutton(app.UIFigure, 'push');
            app.JoinButton.ButtonPushedFcn = createCallbackFcn(app, @JoinButtonPushed, true);
            app.JoinButton.Text = 'Join';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.OtherTextAreaLabel = uilabel(app.UIFigure);
            app.OtherTextAreaLabel.Text = 'Other';
            app.OtherTextArea = uitextarea(app.UIFigure);
            app.RelabelButton = uibutton(app.UIFigure, 'push');
            app.RelabelButton.ButtonPushedFcn = createCallbackFcn(app, @RelabelButtonPushed, true);
            app.RelabelButton.Text = 'Relabel';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Visible = 'off';
            app.SelectButton.Text = 'Select';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.DisentangleButton = uibutton(app.UIFigure, 'push');
            app.DisentangleButton.ButtonPushedFcn = createCallbackFcn(app, @DisentangleButtonPushed, true);
            app.DisentangleButton.Text = 'Disentangle';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.MissingButton = uibutton(app.UIFigure, 'push');
            app.MissingButton.ButtonPushedFcn = createCallbackFcn(app, @MissingButtonPushed, true);
            app.MissingButton.Text = 'Missing';
            app.OtherTextAreaLabel_2 = uilabel(app.UIFigure);
            app.OtherTextAreaLabel_2.Text = '--- No Visual Updates ---';
            app.E_MissingButton = uibutton(app.UIFigure, 'push');
            app.E_MissingButton.ButtonPushedFcn = createCallbackFcn(app, @E_MissingButtonPushed, true);
            app.E_MissingButton.Enable = 'off';
            app.E_MissingButton.Visible = 'off';
            app.E_MissingButton.Text = 'E_Missing';
            app.E_RelabelButton = uibutton(app.UIFigure, 'push');
            app.E_RelabelButton.ButtonPushedFcn = createCallbackFcn(app, @E_RelabelButtonPushed, true);
            app.E_RelabelButton.Enable = 'off';
            app.E_RelabelButton.Visible = 'off';
            app.E_RelabelButton.Text = 'E_Relabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = human_data_labeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end